classdef MAIN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        RoverOptimizationLabel   matlab.ui.control.Label
        btn_generate             matlab.ui.control.Button
        btn_finish               matlab.ui.control.Button
        uipanel4                 matlab.ui.container.Panel
        txt_path                 matlab.ui.control.Label
        txt_log                  matlab.ui.control.TextArea
        uipanel3                 matlab.ui.container.Panel
        RoverstabilitydecreaseswithlargegroundclearanceLabel  matlab.ui.control.Label
        DistancebetweentopofwheelandbottomofframeLabel  matlab.ui.control.Label
        slider_ground_clearance  matlab.ui.control.Slider
        txt_ground_clearance     matlab.ui.control.NumericEditField
        text8_2                  matlab.ui.control.Label
        txt_runtime              matlab.ui.control.NumericEditField
        slider_runtime           matlab.ui.control.Slider
        text7_2                  matlab.ui.control.Label
        slider_top_speed         matlab.ui.control.Slider
        txt_arm_reach            matlab.ui.control.NumericEditField
        slider_arm_reach         matlab.ui.control.Slider
        txt_top_speed            matlab.ui.control.NumericEditField
        txt_wheel_diameter       matlab.ui.control.NumericEditField
        slider_wheel_diameter    matlab.ui.control.Slider
        text8                    matlab.ui.control.Label
        text7                    matlab.ui.control.Label
        text6                    matlab.ui.control.Label
    end

    
    methods (Access = private)
% =========================================================================
% =========================================================================
%                             TEAM NAME   
% =========================================================================
% =========================================================================

% Developed by: <Justin Saikali, Sébastien Olivier, Justin Cheng, Cal Doyle, Yahya Saleh>
% GROUP: ROV3
% University of Ottawa
% Mechanical Engineering
% Latest Revision: 04/12/2024
% MATLAB 
% =========================================================================

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MAIN_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>           
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to MAIN (see VARARGIN)          
            % Choose default command line output for MAIN
            handles.output = hObject;        
            % Update handles structure
            guidata(hObject, handles);          
            %Set the default values on the GUI. It is recommended to choose a valid set
            %of default values as a starting point when the program launches.
            clc
            %Set the window title with the group identification:
            set(handles.figure1,'Name','groupABC | Optimization GUI | uOttawa CAD 2023');  
            %Add the 'subfunctions' folder to the path so that subfunctions can be
            %accessed
            %addpath('Subfunctions');
        end

        % Button pushed function: btn_finish
        function btn_finish_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to BTN_Finish (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close gcf
        end

        % Callback function
        function BTN_Generate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to BTN_Generate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if(isempty(handles))
                Wrong_File(app);
            else
    
                %Show the results on the GUI.

            
                set(handles.TXT_log,'String',S); %write the string into the textbox
                set(handles.TXT_path,'String',log_file); %show the path of the log file
                set(handles.TXT_path,'Visible','on');
            end
        end

        % Button pushed function: btn_generate
        function btn_generatePushed(app, event)
            %%
            %Get the design parameters from the interface 
            %Sliders return numerical values directly
            %----------- Read values from GUI ----------------%
            %returns GUI inputs as numerical value directly
            wheel_diameter = app.slider_wheel_diameter.Value;
            runtime = app.slider_runtime.Value;
            top_speed = app.slider_top_speed.Value;
            arm_reach = app.slider_arm_reach.Value;
            ground_clearance = app.slider_ground_clearance.Value;

            %----------- Call design code function(s) --------%
            %The sample design calculations performed by calling Design_code.m
            log = pipeline(wheel_diameter, ground_clearance, arm_reach, top_speed, runtime);
             
            %----------- Display results from log file -------%
            % Create path variable. Mac/Linux require forward slash for
            % directory paths. Windows agnostic, but is typically back slash.
            %directory_prefix_string = extractBefore(pwd, "ROV3"); %pwd gets current directory, then the directory suffix is extracted.
            %log_file = strcat(directory_prefix_string, '/ROV3/Log/ROV3_LOG.TXT');
            %fid = fopen(log_file,'r'); %Open the log file for reading
            %log_contents=char(fread(fid)'); %Read the file into a string
            %fclose(fid);
            Utils.string_to_file(log,'ROV3_LOG.txt');
            
           %app.txt_log.Value = log_contents;
            app.txt_log.Value = log;
            %app.txt_path.Text = log_file;
            app.txt_log.Enable = 'On';
            
        end

        % Value changed function: txt_arm_reach, txt_ground_clearance, 
        % ...and 3 other components
        function txt_axialValueChanged(app, event)
            app.slider_wheel_diameter.Value = app.txt_wheel_diameter.Value;
            app.slider_arm_reach.Value = app.txt_arm_reach.Value;
            app.slider_runtime.Value = app.txt_runtime.Value;
            app.slider_top_speed.Value = app.txt_top_speed.Value;
            app.slider_ground_clearance.Value = app.txt_ground_clearance.Value;

        end

        % Value changed function: slider_arm_reach, 
        % ...and 4 other components
        function slider_axialValueChanged(app, event)
            app.txt_wheel_diameter.Value = app.slider_wheel_diameter.Value;
            app.txt_arm_reach.Value = app.slider_arm_reach.Value;
            app.txt_runtime.Value = app.slider_runtime.Value;
            app.txt_top_speed.Value = app.slider_top_speed.Value;
            app.txt_ground_clearance.Value = app.slider_ground_clearance.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'MAIN';
            app.figure1.Tag = 'figure1';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Input Parameters';
            app.uipanel3.Tag = 'uipanel3';
            app.text6 = uilabel(app.uipanel3);
            app.text6.Tag = 'text6';
            app.text6.Text = {'Wheel Diameter (mm):'; '            '};
            app.text7 = uilabel(app.uipanel3);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Arm Reach (mm):';
            app.text8 = uilabel(app.uipanel3);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Top Speed (km/h):';
            app.slider_wheel_diameter = uislider(app.uipanel3);
            app.slider_wheel_diameter.Limits = [160 200];
            app.slider_wheel_diameter.MajorTicks = [160 168 176 184 192 200];
            app.slider_wheel_diameter.MajorTickLabels = {'160', '168', '176', '184', '192', '200'};
            app.slider_wheel_diameter.ValueChangedFcn = createCallbackFcn(app, @slider_axialValueChanged, true);
            app.slider_wheel_diameter.Value = 160;
            app.txt_wheel_diameter = uieditfield(app.uipanel3, 'numeric');
            app.txt_wheel_diameter.Limits = [160 200];
            app.txt_wheel_diameter.ValueChangedFcn = createCallbackFcn(app, @txt_axialValueChanged, true);
            app.txt_wheel_diameter.Value = 160;
            app.txt_top_speed = uieditfield(app.uipanel3, 'numeric');
            app.txt_top_speed.Limits = [1 8];
            app.txt_top_speed.ValueChangedFcn = createCallbackFcn(app, @txt_axialValueChanged, true);
            app.txt_top_speed.Value = 1;
            app.slider_arm_reach = uislider(app.uipanel3);
            app.slider_arm_reach.Limits = [712 810];
            app.slider_arm_reach.ValueChangedFcn = createCallbackFcn(app, @slider_axialValueChanged, true);
            app.slider_arm_reach.Value = 712;
            app.txt_arm_reach = uieditfield(app.uipanel3, 'numeric');
            app.txt_arm_reach.Limits = [712 810];
            app.txt_arm_reach.ValueChangedFcn = createCallbackFcn(app, @txt_axialValueChanged, true);
            app.txt_arm_reach.Value = 712;
            app.slider_top_speed = uislider(app.uipanel3);
            app.slider_top_speed.Limits = [1 8];
            app.slider_top_speed.ValueChangedFcn = createCallbackFcn(app, @slider_axialValueChanged, true);
            app.slider_top_speed.Value = 1;
            app.text7_2 = uilabel(app.uipanel3);
            app.text7_2.Tag = 'text7';
            app.text7_2.Text = 'Runtime (minutes):';
            app.slider_runtime = uislider(app.uipanel3);
            app.slider_runtime.Limits = [30 180];
            app.slider_runtime.ValueChangedFcn = createCallbackFcn(app, @slider_axialValueChanged, true);
            app.slider_runtime.Value = 30;
            app.txt_runtime = uieditfield(app.uipanel3, 'numeric');
            app.txt_runtime.Limits = [30 180];
            app.txt_runtime.ValueChangedFcn = createCallbackFcn(app, @txt_axialValueChanged, true);
            app.txt_runtime.Value = 30;
            app.text8_2 = uilabel(app.uipanel3);
            app.text8_2.Tag = 'text8';
            app.text8_2.Text = '* Ground Clearance (mm):';
            app.txt_ground_clearance = uieditfield(app.uipanel3, 'numeric');
            app.txt_ground_clearance.Limits = [75 300];
            app.txt_ground_clearance.ValueChangedFcn = createCallbackFcn(app, @txt_axialValueChanged, true);
            app.txt_ground_clearance.Value = 75;
            app.slider_ground_clearance = uislider(app.uipanel3);
            app.slider_ground_clearance.Limits = [75 300];
            app.slider_ground_clearance.ValueChangedFcn = createCallbackFcn(app, @slider_axialValueChanged, true);
            app.slider_ground_clearance.Value = 75;
            app.DistancebetweentopofwheelandbottomofframeLabel = uilabel(app.uipanel3);
            app.DistancebetweentopofwheelandbottomofframeLabel.Text = {'* Distance between top of wheel and bottom of frame. '; '   '};
            app.RoverstabilitydecreaseswithlargegroundclearanceLabel = uilabel(app.uipanel3);
            app.RoverstabilitydecreaseswithlargegroundclearanceLabel.Text = 'Rover stability decreases with large ground clearance.';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Output Log';
            app.uipanel4.Tag = 'uipanel4';
            app.txt_log = uitextarea(app.uipanel4);
            app.txt_log.Tag = 'TXT_log';
            app.txt_log.Enable = 'off';
            app.txt_log.Value = {'Chose a set of desired design input parameters, then...'; ''; 'Click the generate button!'};
            app.txt_path = uilabel(app.uipanel4);
            app.txt_path.Tag = 'TXT_path';
            app.txt_path.Text = 'ROV3_LOG.TXT';
            app.btn_finish = uibutton(app.figure1, 'push');
            app.btn_finish.ButtonPushedFcn = createCallbackFcn(app, @btn_finish_Callback, true);
            app.btn_finish.Tag = 'BTN_Finish';
            app.btn_finish.Text = 'CLOSE';
            app.btn_generate = uibutton(app.figure1, 'push');
            app.btn_generate.ButtonPushedFcn = createCallbackFcn(app, @btn_generatePushed, true);
            app.btn_generate.Text = 'GENERATE';
            app.RoverOptimizationLabel = uilabel(app.figure1);
            app.RoverOptimizationLabel.Text = 'Rover Optimization';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAIN(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)MAIN_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end